classdef manual_multisession_align < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                    matlab.ui.Figure
        DisplaysettingsPanel            matlab.ui.container.Panel
        DisplayallCheckBox              matlab.ui.control.CheckBox
        ROInumberCheckBox               matlab.ui.control.CheckBox
        DisplayspatialcomponentCheckBox  matlab.ui.control.CheckBox
        ROIsizeEditFieldLabel           matlab.ui.control.Label
        ROIsizeEditField                matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        RegistertemplatesfromROIsPanel  matlab.ui.container.Panel
        RegisterButton                  matlab.ui.control.Button
        TemplateEditFieldLabel          matlab.ui.control.Label
        TemplateEditField               matlab.ui.control.NumericEditField
        horizontalEditFieldLabel        matlab.ui.control.Label
        horizontalEditField             matlab.ui.control.NumericEditField
        ROIsPanel                       matlab.ui.container.Panel
        AddROIButton                    matlab.ui.control.Button
        DeleteROIButton                 matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        ROIsListBox                     matlab.ui.control.ListBox
        TemplatePanel                   matlab.ui.container.Panel
        LoadtemplateButton              matlab.ui.control.Button
        AddonFrameButton                matlab.ui.control.Button
        DeleteonFrameButton             matlab.ui.control.Button
        LoadspatialcomponentButton      matlab.ui.control.Button
        TemplateSliderLabel             matlab.ui.control.Label
        TemplateSlider                  matlab.ui.control.Slider
        BrightnessMaxSliderLabel        matlab.ui.control.Label
        BrightnessMaxSlider             matlab.ui.control.Slider
        BrightnessMinSliderLabel        matlab.ui.control.Label
        BrightnessMinSlider             matlab.ui.control.Slider
        UIAxes                          matlab.ui.control.UIAxes
        verticalEditFieldLabel          matlab.ui.control.Label
        verticalEditField               matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function [] = update_figure(app, data)
            
            imagesc(data.ax, data.template{data.day}); 
            dims = size(data.template{data.day});
            xlim(data.ax, [0 dims(2)]); ylim(data.ax, [0 dims(1)]);
            caxis(data.ax, data.clim{data.day});
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            hold(data.ax, 'on');
            
            if ~app.DisplayallCheckBox.Value
                if ~isnan(data.roi_idx) && ~isnan(data.roi_coord(data.roi_idx,data.day,1))
                    roi_cc = data.roi_cc(mod(data.roi_idx, size(data.roi_cc,1))+1, :);
                    scatter(data.ax, data.roi_coord(data.roi_idx,data.day,1), data.roi_coord(data.roi_idx,data.day,2), ...
                        data.mksz, roi_cc, 'filled', 'markeredgecolor', 'none', 'markerfacealpha', data.mkalpha);
                    if app.ROInumberCheckBox.Value
                        text(data.ax, data.roi_coord(data.roi_idx,data.day,1), data.roi_coord(data.roi_idx,data.day,2), ...
                            num2str(data.roi_idx), 'FontSize', 8, 'Color', [1 1 0]);
                    end
                end
            elseif app.DisplayallCheckBox.Value
                if ~all(isnan(data.roi_coord(:,data.day,1)))
                    roi_cc = data.roi_cc(mod(1:size(data.roi_coord,1), size(data.roi_cc,1))+1, :);
                    scatter(data.ax, data.roi_coord(:,data.day,1), data.roi_coord(:,data.day,2),...
                        data.mksz, roi_cc, 'filled', 'markeredgecolor', 'none', 'markerfacealpha', data.mkalpha);
                    if app.ROInumberCheckBox.Value
                        for roi_idx = 1:size(data.roi_coord,1)
                            if ~isnan(data.roi_coord(roi_idx,data.day,1))
                                text(data.ax, data.roi_coord(roi_idx,data.day,1), data.roi_coord(roi_idx,data.day,2), ...
                                    num2str(roi_idx), 'FontSize', 8, 'Color', [1 1 0]);
                            end
                        end
                    end
                end
            end
            
            if app.DisplayspatialcomponentCheckBox.Value
                if ~isempty(data.roi_contour_combined{data.day})
                    cc = [1 1 0.6];
                    scatter(data.ax, data.roi_contour_combined{data.day}(:,2), data.roi_contour_combined{data.day}(:,1), 0.1, cc);
%                       contour(data.ax2, data.roi_mask{data.day});
%                     imagesc(data.ax2, 1-data.roi_mask{data.day}, 'AlphaData', 0.1);
%                     colormap(data.ax2, 'hot'); caxis(data.ax2, [-3 1]);
%                     data.ax2.UserData = linkprop([data.ax, data.ax2],...
%                         {'Position','InnerPosition','DataAspectRatio','xtick','ytick', ...
%                         'ydir','xdir','xlim','ylim'});
%                     data.ax2.Visible = 'off';
                end
            end
            
            hold(data.ax, 'off');
            
        end
        
        function [data] = update_template(app, data, new_val)
            
            % round up
            app.TemplateSlider.Value = round(new_val);
            
            % update data
            data.day = new_val;
            app.TemplateSlider.Limits = [1 data.num_data];
            app.TemplateSlider.MajorTicks = 1:data.num_data;
            
            % update brightness scale
            app.BrightnessMinSlider.Limits = [data.clim{data.day}(1) data.clim{data.day}(2)];
            app.BrightnessMaxSlider.Limits = [data.clim{data.day}(1) data.clim{data.day}(2)];
            app.BrightnessMinSlider.Value = data.clim{data.day}(1);
            app.BrightnessMaxSlider.Value = data.clim{data.day}(2);
            
            % update template plot
            hold(data.ax, 'off');
            imagesc(data.ax, data.template{new_val});
            hold(data.ax, 'on');
            dims = size(data.template{new_val});
            xlim(data.ax, [0 dims(2)]); ylim(data.ax, [0 dims(1)]);
            caxis(data.ax, data.clim{data.day});
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            hold(data.ax, 'off');
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = guidata(app.MainUIFigure);
            data.ax = [];
            guidata(app.MainUIFigure, data);
            
%             pos = [0.0288    0.1025    0.7063    0.8027];
%             pos = [0.0300    0.1100    0.6956    0.8050];
%             ax = axes('Parent', app.MainUIFigure, 'position', pos);
%             set(ax, 'xtick', [], 'ytick', []);
%             set(ax, 'box', 'on');
%             set(ax ,'Layer', 'Top')
%             
%             data.ax = ax;
%             guidata(app.MainUIFigure, data);
            
        end

        % Button pushed function: LoadtemplateButton
        function LoadtemplateButtonPushed(app, event)
            
            [fname, fpath] = uigetfile({'*.mat','MAT-files'}, 'Select template file');
            if fname==0; return; end
            ld = load([fpath fname]);
            ld.template = cellfun(@(x) double(x), ld.template, 'uniformoutput', 0);
            data = guidata(app.MainUIFigure);
            
            % update figure
%             pos = [0.0300    0.1500    0.6956    0.7550];
            if isempty(data.ax)
                pos = app.UIAxes.Position./app.TemplatePanel.Position([3,4,3,4]);
                delete(app.UIAxes);
            else
                ax_obj = findall(app.TemplatePanel.Children, 'Type', 'Axes');
                pos = get(ax_obj, 'Position');
                delete(ax_obj);
            end
            ax = axes('Parent', app.TemplatePanel, 'position', pos);
            set(ax, 'xtick', [], 'ytick', []);
            set(ax, 'box', 'on');
            set(ax ,'Layer', 'Top');
            
            data.ax = ax;
            data.template = ld.template;
            imagesc(data.ax, ld.template{1});
            hold(data.ax, 'on');
            dims = size(ld.template{1});
            xlim(data.ax, [0 dims(2)]); ylim(ax, [0 dims(1)]);
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            num_data = length(ld.template);
            data.num_data = num_data;
            hold(data.ax, 'off');
            
            % initialize
            data.roi_coord = nan(0,data.num_data,2);
            data.roi_cc = jet(50); 
            data.roi_cc = data.roi_cc(randperm(50),:);
            data.mksz = app.ROIsizeEditField.Value*10;
            data.mkalpha = app.AlphaEditField.Value;
            data.roi_idx = NaN;
            data.clim = cell(1,num_data);
            data.roi_mask = cell(1,num_data);
            data.roi_contour_combined = cell(1,num_data);
            
            % remove all current ROIs
            app.ROIsListBox.Items = {};
            app.ROIsListBox.ItemsData = [];
            app.ROIsListBox.Value = {};
            
            % update slider
            app.TemplateSlider.Limits = [1 num_data];
            app.TemplateSlider.MajorTicks = 1:num_data;
            data.day = 1;
            for i = 1:data.num_data
                data.clim{i} = [min(data.template{i}(:)) max(data.template{i}(:))];
            end
            app.BrightnessMinSlider.Limits = [data.clim{1}(1) data.clim{1}(2)];
            app.BrightnessMaxSlider.Limits = [data.clim{1}(1) data.clim{1}(2)];
            app.BrightnessMinSlider.Value = data.clim{1}(1);
            app.BrightnessMaxSlider.Value = data.clim{1}(2);
            
            % set default template register number
            app.TemplateEditField.Value = 1;
            
            % store data
            guidata(app.MainUIFigure, data);
            
        end

        % Value changed function: TemplateSlider
        function TemplateSliderValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            % round up
            new_val = round(app.TemplateSlider.Value);
            data.day = new_val;
            
            app.BrightnessMinSlider.Limits = [min(data.template{data.day}(:)) max(data.template{data.day}(:))];
            app.BrightnessMaxSlider.Limits = [min(data.template{data.day}(:)) max(data.template{data.day}(:))];
            app.BrightnessMinSlider.Value = data.clim{data.day}(1);
            app.BrightnessMaxSlider.Value = data.clim{data.day}(2);
            
            % update template and roi
            update_template(app, data, new_val);
            update_figure(app, data);
            
            % save data
            guidata(app.MainUIFigure, data);
            
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            
            % add row in list
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            data.day = app.TemplateSlider.Value;
            roi_list = app.ROIsListBox.Items;
            data.roi_idx = length(roi_list)+1;
            new_item = ['ROI' num2str(data.roi_idx)];
            app.ROIsListBox.Items = cat(2, roi_list, new_item);
            app.ROIsListBox.ItemsData(data.roi_idx) = data.roi_idx;
            
            data.roi_coord(data.roi_idx,:,:) = NaN;
            
            % make current selection default
            app.ROIsListBox.Value = data.roi_idx;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Callback function
        function UITableCellSelection(app, event)

            
        end

        % Button pushed function: AddonFrameButton
        function AddonFrameButtonPushed(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            % select from figure
            current_roi = drawcircle(data.ax, 'visible', 'off');
            try
                data.roi_coord(data.roi_idx,data.day,:) = current_roi.Position;
            catch ME     
            end
            update_figure(app, data);
            
            guidata(app.MainUIFigure, data);
            
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            
            % get current roi selection index
            data = guidata(app.MainUIFigure);
            data.roi_idx = app.ROIsListBox.Value;
            guidata(app.MainUIFigure, data);
            update_figure(app, data);
            
        end

        % Value changed function: DisplayallCheckBox
        function DisplayallCheckBoxValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
            
        end

        % Value changed function: ROInumberCheckBox
        function ROInumberCheckBoxValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
            
        end

        % Window scroll wheel function: MainUIFigure
        function MainUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            new_val = app.TemplateSlider.Value + round(verticalScrollCount);
            new_val = max(min(new_val, data.num_data), 1);
            data.day = new_val;
    
            % update template and roi
            update_template(app, data, new_val);
            update_figure(app, data);
            
            % save data
            guidata(app.MainUIFigure, data);
            
            
        end

        % Button pushed function: DeleteonFrameButton
        function DeleteonFrameButtonPushed(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            % select from figure
            data.roi_coord(data.roi_idx,data.day,:) = [NaN, NaN];
            update_figure(app, data);
            
            guidata(app.MainUIFigure, data);
            
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            % delete row in list
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            data.day = app.TemplateSlider.Value;
            roi_to_remove = data.roi_idx;
            keep_idx = setdiff(1:length(app.ROIsListBox.Items), roi_to_remove);
            app.ROIsListBox.Items = app.ROIsListBox.Items(keep_idx);
            app.ROIsListBox.ItemsData = app.ROIsListBox.ItemsData(keep_idx);
            data.roi_coord = data.roi_coord(keep_idx,:,:);
            
            data.roi_idx = max(1, roi_to_remove-1);
            
            % make current selection default
            if ~isempty(app.ROIsListBox.Value)
                app.ROIsListBox.Value = data.roi_idx;
            else
                app.ROIsListBox.Value = {};
                data.roi_idx = NaN;
            end
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Key press function: MainUIFigure
        function MainUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'a'
                    AddonFrameButtonPushed(app, event);
                case 'd'
                    DeleteonFrameButtonPushed(app, event);
            end
        end

        % Value changed function: ROIsizeEditField
        function ROIsizeEditFieldValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            data.mksz = app.ROIsizeEditField.Value*10;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            data.mkalpha = app.AlphaEditField.Value;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            roi_coord = data.roi_coord;
            
            [fname, fpath] = uiputfile({'*.mat','MAT-files'}, 'Save ROI result');
            if fname==0; return; end
            save([fpath fname], 'roi_coord', '-v7.3');
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            [fname, fpath] = uigetfile({'*.mat','MAT-files'}, 'Load ROI result');
            if fname==0; return; end
            ld = load([fpath fname]);
            
            if ~isfield(ld, 'roi_coord')
                uialert(app.MainUIFigure,'Invalid file content','Invalid File');
            end
            data.roi_coord = ld.roi_coord;
            
            roi_str = cell(1,size(data.roi_coord,1));
            for i = 1:size(data.roi_coord,1)
                roi_str{i} = ['ROI' num2str(i)];
            end
            app.ROIsListBox.Items = roi_str;
            app.ROIsListBox.ItemsData = 1:size(data.roi_coord,1);
            data.roi_idx = 1;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
            
        end

        % Callback function
        function LoadspatialcomponentButtonPushed(app, event)
            
        end

        % Button pushed function: LoadspatialcomponentButton
        function LoadspatialcomponentButtonPushed2(app, event)
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            
            [fname, fpath] = uigetfile({'*.mat','MAT-files'}, 'Load ROI spatial component');
            if fname==0; return; end
            ld = load([fpath fname]);
            
            if ~isfield(ld, 'ROI_mask')
                uialert(app.MainUIFigure,'Invalid file content','Invalid File');
            end
%             data.roi_mask = ld.ROI_mask;
            
%             data.ax2 = copyobj(data.ax, app.MainUIFigure);

            roi_center = cell(1,data.num_data);
            roi_contour = cell(1,data.num_data);
            roi_contour_combined = cell(1,data.num_data);
            for i = 1:data.num_data
%                 roi_contour{i} = cell2mat(bwboundaries(full(data.roi_mask{i}~=0)));
                roi_contour{i} = ld.ROI_mask{i}.cont;
                roi_center{i} = ld.ROI_mask{i}.cent;
                roi_contour_combined{i} = cell2mat(roi_contour{i}');
                % downsample
                roi_contour_combined{i} = roi_contour_combined{i}(2:2:end,:);
            end
            data.roi_contour = roi_contour;
            data.roi_center = roi_center;
            data.roi_contour_combined = roi_contour_combined;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
        end

        % Value changed function: DisplayspatialcomponentCheckBox
        function DisplayspatialcomponentCheckBoxValueChanged(app, event)
           
            data = guidata(app.MainUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
            
        end

        % Value changed function: BrightnessMinSlider
        function BrightnessMinSliderValueChanged(app, event)
            value = app.BrightnessMinSlider.Value;
            data = guidata(app.MainUIFigure);
            data.clim{data.day}(1) = value;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
        end

        % Value changed function: BrightnessMaxSlider
        function BrightnessMaxSliderValueChanged(app, event)
            value = app.BrightnessMaxSlider.Value;
            data = guidata(app.MainUIFigure);
            data.clim{data.day}(2) = value;
            
            update_figure(app, data);
            guidata(app.MainUIFigure, data);
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            set(app.RegisterButton,'Enable','off');
            pause(0.01);
            
            data = guidata(app.MainUIFigure);
            idx_ref = app.TemplateEditField.Value;
            if idx_ref<1 || idx_ref>data.num_data
                uialert(app.MainUIFigure, 'Value error', 'Invalid template number');
                return;
            end
            
%             h_piece = 1; v_piece = 3;
            h_piece = app.horizontalEditField.Value;
            v_piece = app.verticalEditField.Value;
            xdim = zeros(1,data.num_data);  ydim = zeros(1,data.num_data);
            for n = 1:data.num_data
                ydim(n) = floor(size(data.template{n},1)/v_piece);
                xdim(n) = floor(size(data.template{n},2)/h_piece);
            end
            
            % register with the previous/next template
            tform = cell(1, data.num_data);
            for n = setdiff(1:data.num_data, idx_ref)
                if n<idx_ref
                    idx_fix = n+1;
                else
                    idx_fix = n-1;
                end
%                 points_fix = squeeze(data.roi_coord(:,idx_fix,:));
%                 points_mov = squeeze(data.roi_coord(:,n,:));
%                 good_idx = all(~isnan(points_ref),2) & all(~isnan(points_mov),2);
%                 tform{n} = fitgeotrans(points_mov(good_idx,:), points_fix(good_idx,:),'projective');
                for i = 1:h_piece
                    for j = 1:v_piece 
                        points_fix = squeeze(data.roi_coord(:,idx_fix,:));
                        points_mov = squeeze(data.roi_coord(:,n,:));
                        good_idx = all(~isnan(points_fix),2) & all(~isnan(points_mov),2);
                        points_fix = points_fix(good_idx,:);
                        points_mov = points_mov(good_idx,:);
                        
                        % keep only coordinates within piece
                        idx = (points_fix(:,1)>(i-1)*xdim(idx_fix)) & (points_fix(:,1)<=i*xdim(idx_fix)) & ...
                            (points_fix(:,2)>(j-1)*ydim(idx_fix)) & (points_fix(:,2)<=j*ydim(idx_fix));
                        points_fix = points_fix(idx,:);
                        points_fix(:,1) = points_fix(:,1)-(i-1)*xdim(idx_fix); 
                        points_fix(:,2) = points_fix(:,2)-(j-1)*ydim(idx_fix); 
                        
                        idx = (points_mov(:,1)>(i-1)*xdim(n)) & (points_mov(:,1)<=i*xdim(n)) & ...
                            (points_mov(:,2)>(j-1)*ydim(n)) & (points_mov(:,2)<=j*ydim(n));
                        points_mov = points_mov(idx,:);
                        points_mov(:,1) = points_mov(:,1)-(i-1)*xdim(n); 
                        points_mov(:,2) = points_mov(:,2)-(j-1)*ydim(n); 
                        
                        % TODO: implement error warning
                        try
                            tform{n}{i,j} = fitgeotrans(points_mov, points_fix,'projective');
                        catch ME
                            uialert(app.MainUIFigure,'Each piece needs at least four registration points!','Error');
                            set(app.RegisterButton,'Enable','on');
                            return;
                        end
                    end
                end
            end
            
            % transform to the final reference
            % NOTE: output dimensions might have error; m and idx_ref are used interchangebly here
%             ref = imref2d(size(data.template{idx_ref}));
            ref = imref2d([ydim(idx_ref), xdim(idx_ref)]);
            data.template_reg = cell(1, data.num_data);
            data.roi_contour_reg = cell(1, data.num_data);
            data.roi_center_reg = cell(1, data.num_data);
            for n = 1:data.num_data
                im = data.template{n};
                cont = data.roi_contour{n};
                cent = data.roi_center{n};
                if n<idx_ref
                    m_seq = n:idx_ref-1;
                elseif n>idx_ref
                    m_seq = n:-1:idx_ref+1;
                else
                    data.template_reg{n} = im;
                    data.roi_contour_reg{n} = cont;
                    data.roi_center_reg{n} = cent;
                    continue;
                end
                for m = m_seq
            % transform template
%                         im = imwarp(im, tform{m}, 'nearest', 'OutputView', ref);
%                         for k = 1:length(data.roi_contour{n})
%                             [cont{k}(:,2), cont{k}(:,1)] = transformPointsForward(tform{m}, cont{k}(:,2), cont{k}(:,1));
%                         end
%                         cent = transformPointsForward(tform{m}, cent);
                    
                    im_out = zeros(size(data.template{idx_ref}));
                    for i = 1:h_piece
                        for j = 1:v_piece
                            % piecewise register template
                            ystart = (j-1)*ydim(idx_ref)+1;  yend = j*ydim(idx_ref);
                            xstart = (i-1)*xdim(idx_ref)+1;  xend = i*xdim(idx_ref);
                            im_piece = im(ystart:yend, xstart:xend);
                            im_piece_reg = imwarp(im_piece, tform{m}{i,j}, 'nearest', 'OutputView', ref);
                            im_out((j-1)*ydim(idx_ref)+1:j*ydim(idx_ref), (i-1)*xdim(idx_ref)+1:i*xdim(idx_ref)) = im_piece_reg;
                            
                            % piecewise register coordinates
                            idx = find((cent(:,1)>(i-1)*xdim(m)) & (cent(:,1)<=i*xdim(m)) & (cent(:,2)>(j-1)*ydim(m)) & (cent(:,2)<=j*ydim(m)));
                            for k = 1:length(idx)
                                cont_in = cont{idx(k)}(:,[2,1]);
                                cont_in(:,2) = cont_in(:,2) - (j-1)*ydim(m);
                                cont_in(:,1) = cont_in(:,1) - (i-1)*xdim(m);
                                cont_out = transformPointsForward(tform{m}{i,j}, cont_in);
                                cont_out(:,2) = cont_out(:,2) + (j-1)*ydim(m);
                                cont_out(:,1) = cont_out(:,1) + (i-1)*xdim(m);
                                cont{idx(k)} = cont_out(:,[2,1]);
                            end
                            cent_in = cent(idx,:);
                            cent_in(:,2) = cent_in(:,2) - (j-1)*ydim(m);
                            cent_in(:,1) = cent_in(:,1) - (i-1)*xdim(m);
                            cent_out = transformPointsForward(tform{m}{i,j}, cent_in);
                            cent_out(:,2) = cent_out(:,2) + (j-1)*ydim(idx_ref);
                            cent_out(:,1) = cent_out(:,1) + (i-1)*xdim(idx_ref);
                            cent(idx,:) = cent_out;
                        end
                    end
                    im = im_out;
                end
                
                data.template_reg{n} = im;
                data.roi_contour_reg{n} = cont;
                data.roi_center_reg{n} = cent;
            end
            
            guidata(app.MainUIFigure, data);
            set(app.RegisterButton,'Enable','on');
            view_multisession_align;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure;
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @MainUIFigureWindowScrollWheel, true);
            app.MainUIFigure.KeyPressFcn = createCallbackFcn(app, @MainUIFigureKeyPress, true);
            app.DisplaysettingsPanel = uipanel(app.MainUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.DisplayallCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.DisplayallCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayallCheckBoxValueChanged, true);
            app.DisplayallCheckBox.Text = 'Display all';
            app.ROInumberCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.ROInumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @ROInumberCheckBoxValueChanged, true);
            app.ROInumberCheckBox.Text = 'ROI number';
            app.DisplayspatialcomponentCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.DisplayspatialcomponentCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayspatialcomponentCheckBoxValueChanged, true);
            app.DisplayspatialcomponentCheckBox.Text = 'Display spatial component';
            app.ROIsizeEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.ROIsizeEditFieldLabel.Text = 'ROI size';
            app.ROIsizeEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.ROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ROIsizeEditFieldValueChanged, true);
            app.ROIsizeEditField.Tooltip = {'For visualization only'};
            app.ROIsizeEditField.Value = 10;
            app.AlphaEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Tooltip = {'For visualization only'};
            app.AlphaEditField.Value = 0.4;
            app.RegistertemplatesfromROIsPanel = uipanel(app.MainUIFigure);
            app.RegistertemplatesfromROIsPanel.Title = 'Register templates from ROIs';
            app.RegisterButton = uibutton(app.RegistertemplatesfromROIsPanel, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.TemplateEditFieldLabel = uilabel(app.RegistertemplatesfromROIsPanel);
            app.TemplateEditFieldLabel.Text = 'Template';
            app.TemplateEditField = uieditfield(app.RegistertemplatesfromROIsPanel, 'numeric');
            app.TemplateEditField.Tooltip = {'Template used for registration'};
            app.TemplateEditField.Value = 1;
            app.horizontalEditFieldLabel = uilabel(app.RegistertemplatesfromROIsPanel);
            app.horizontalEditFieldLabel.Text = 'horizontal';
            app.horizontalEditField = uieditfield(app.RegistertemplatesfromROIsPanel, 'numeric');
            app.horizontalEditField.Tooltip = {'Number of horizontal pieces'; ' for piece-wise registration'};
            app.horizontalEditField.Value = 1;
            app.ROIsPanel = uipanel(app.MainUIFigure);
            app.ROIsPanel.Title = 'ROIs';
            app.AddROIButton = uibutton(app.ROIsPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Tooltip = {'Add a new ROI to list'};
            app.AddROIButton.Text = 'Add ROI';
            app.DeleteROIButton = uibutton(app.ROIsPanel, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Tooltip = {'Delete current ROI'};
            app.DeleteROIButton.Text = 'Delete ROI';
            app.SaveButton = uibutton(app.ROIsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.ROIsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ROIsListBox = uilistbox(app.ROIsPanel);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {};
            app.TemplatePanel = uipanel(app.MainUIFigure);
            app.TemplatePanel.Title = 'Template';
            app.LoadtemplateButton = uibutton(app.TemplatePanel, 'push');
            app.LoadtemplateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtemplateButtonPushed, true);
            app.LoadtemplateButton.Text = 'Load template';
            app.AddonFrameButton = uibutton(app.TemplatePanel, 'push');
            app.AddonFrameButton.ButtonPushedFcn = createCallbackFcn(app, @AddonFrameButtonPushed, true);
            app.AddonFrameButton.Tooltip = {'Add ROI on current frame (shortcut ''a'')'};
            app.AddonFrameButton.Text = 'Add on Frame';
            app.DeleteonFrameButton = uibutton(app.TemplatePanel, 'push');
            app.DeleteonFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteonFrameButtonPushed, true);
            app.DeleteonFrameButton.Tooltip = {'Delete ROI on current frame (shortcut ''d'')'};
            app.DeleteonFrameButton.Text = 'Delete on Frame';
            app.LoadspatialcomponentButton = uibutton(app.TemplatePanel, 'push');
            app.LoadspatialcomponentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadspatialcomponentButtonPushed2, true);
            app.LoadspatialcomponentButton.Text = 'Load spatial component';
            app.TemplateSliderLabel = uilabel(app.TemplatePanel);
            app.TemplateSliderLabel.Text = 'Template';
            app.TemplateSlider = uislider(app.TemplatePanel);
            app.TemplateSlider.MajorTicks = [];
            app.TemplateSlider.ValueChangedFcn = createCallbackFcn(app, @TemplateSliderValueChanged, true);
            app.TemplateSlider.MinorTicks = [];
            app.BrightnessMaxSliderLabel = uilabel(app.TemplatePanel);
            app.BrightnessMaxSliderLabel.Text = 'Brightness Max';
            app.BrightnessMaxSlider = uislider(app.TemplatePanel);
            app.BrightnessMaxSlider.MajorTicks = [];
            app.BrightnessMaxSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessMaxSliderValueChanged, true);
            app.BrightnessMaxSlider.MinorTicks = [];
            app.BrightnessMinSliderLabel = uilabel(app.TemplatePanel);
            app.BrightnessMinSliderLabel.Text = 'Brightness Min';
            app.BrightnessMinSlider = uislider(app.TemplatePanel);
            app.BrightnessMinSlider.MajorTicks = [];
            app.BrightnessMinSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessMinSliderValueChanged, true);
            app.BrightnessMinSlider.MinorTicks = [];
            app.UIAxes = uiaxes(app.TemplatePanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.verticalEditFieldLabel = uilabel(app.MainUIFigure);
            app.verticalEditFieldLabel.Text = 'vertical';
            app.verticalEditField = uieditfield(app.MainUIFigure, 'numeric');
            app.verticalEditField.Tooltip = {'Number of vertical pieces'; ' for piece-wise registration'};
            app.verticalEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = manual_multisession_align

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end