classdef view_multisession_align < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ViewresultsUIFigure         matlab.ui.Figure
        MatchROIsPanel              matlab.ui.container.Panel
        MatchROIsButton             matlab.ui.control.Button
        MaxdistEditFieldLabel       matlab.ui.control.Label
        MaxdistEditField            matlab.ui.control.NumericEditField
        ShowmindaysEditFieldLabel   matlab.ui.control.Label
        ShowmindaysEditField        matlab.ui.control.NumericEditField
        ShowmaxdaysEditFieldLabel   matlab.ui.control.Label
        ShowmaxdaysEditField        matlab.ui.control.NumericEditField
        SaveButton                  matlab.ui.control.Button
        DisplaysettingsPanel        matlab.ui.container.Panel
        DisplayallCheckBox          matlab.ui.control.CheckBox
        ROInumberCheckBox           matlab.ui.control.CheckBox
        ROIsizeEditFieldLabel       matlab.ui.control.Label
        ROIsizeEditField            matlab.ui.control.NumericEditField
        TransparencyEditFieldLabel  matlab.ui.control.Label
        TransparencyEditField       matlab.ui.control.NumericEditField
        DisplayspatialcomponentCheckBox  matlab.ui.control.CheckBox
        RegistrationresultPanel     matlab.ui.container.Panel
        TemplateSliderLabel         matlab.ui.control.Label
        TemplateSlider              matlab.ui.control.Slider
        BrightnessMaxSliderLabel    matlab.ui.control.Label
        BrightnessMaxSlider         matlab.ui.control.Slider
        BrightnessMinSliderLabel    matlab.ui.control.Label
        BrightnessMinSlider         matlab.ui.control.Slider
        ROIsPanel                   matlab.ui.container.Panel
        ROIsListBox                 matlab.ui.control.ListBox
        DeleteROIButton             matlab.ui.control.Button
        DelonFrameButton            matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [] = update_figure(app, data)
            
            imagesc(data.ax, data.template{data.day}); 
            dims = size(data.template{data.day});
            xlim(data.ax, [0 dims(2)]); ylim(data.ax, [0 dims(1)]);
            caxis(data.ax, data.clim{data.day});
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            hold(data.ax, 'on');
            
            if ~app.DisplayallCheckBox.Value && ~isempty(data.show_idx)
                current_spatial_idx = data.roi_list(data.show_idx(data.roi_idx), data.day);
                if ~isnan(data.roi_idx) && ~isnan(current_spatial_idx)
                    roi_cc = data.roi_cc(mod(data.roi_idx, size(data.roi_cc,1))+1, :);
                    scatter(data.ax, data.roi_center{data.day}(current_spatial_idx,1), data.roi_center{data.day}(current_spatial_idx,2), ...
                        data.mksz, roi_cc, 'filled', 'markeredgecolor', 'none', 'markerfacealpha', data.mkalpha);
                    if app.ROInumberCheckBox.Value
                        text(data.ax, data.roi_center{data.day}(current_spatial_idx,1), data.roi_center{data.day}(current_spatial_idx,2), ...
                            num2str(data.roi_idx), 'FontSize', 8, 'Color', [1 1 0]);
                    end
                end
            elseif app.DisplayallCheckBox.Value && ~isempty(data.show_idx)
                if ~all(isnan(data.roi_list(data.show_idx, data.day)))
                    roi_cc = data.roi_cc(mod(1:length(data.show_idx), size(data.roi_cc,1))+1, :);
                    current_spatial_idx = data.roi_list(data.show_idx, data.day);
                    valid_idx = ~isnan(current_spatial_idx);
                    roi_coord = data.roi_center{data.day}(current_spatial_idx(valid_idx),:);
                    roi_cc = roi_cc(valid_idx,:);
                    scatter(data.ax, roi_coord(:,1), roi_coord(:,2), data.mksz, roi_cc, 'filled', ...
                        'markeredgecolor', 'none', 'markerfacealpha', data.mkalpha);
                    if app.ROInumberCheckBox.Value
                        for roi_idx = 1:length(data.show_idx)
                            current_spatial_idx = data.roi_list(data.show_idx(roi_idx), data.day);
                            if ~isnan(current_spatial_idx)
                                text(data.ax, data.roi_center{data.day}(current_spatial_idx,1), ...
                                    data.roi_center{data.day}(current_spatial_idx,2), ...
                                    num2str(roi_idx), 'FontSize', 8, 'Color', [1 1 0]);
                            end
                        end
                    end
                end
            end
            
            if app.DisplayspatialcomponentCheckBox.Value && ~isempty(data.show_idx)
                if ~isempty(data.roi_contour_combined{data.day})
                    cc = [1 1 0.6];
%                     scatter(data.ax, data.roi_contour_combined{data.day}(:,2), data.roi_contour_combined{data.day}(:,1), 0.1, cc);
                    current_spatial_idx = data.roi_list(data.show_idx, data.day);
                    valid_idx = ~isnan(current_spatial_idx);
                    roi_contour_valid = cell2mat(data.roi_contour_reg{data.day}(current_spatial_idx(valid_idx))');
                    scatter(data.ax, roi_contour_valid(:,2), roi_contour_valid(:,1), 0.1, cc);
                end
            end
            
            hold(data.ax, 'off');
            
        end
        
        function [data] = update_template(app, data, new_val)
            
            % round up
            app.TemplateSlider.Value = round(new_val);
            
            % update data
            data.day = new_val;
            app.TemplateSlider.Limits = [1 data.num_data];
            app.TemplateSlider.MajorTicks = 1:data.num_data;
            
            % update brightness scale
            app.BrightnessMinSlider.Limits = [data.clim{data.day}(1) data.clim{data.day}(2)];
            app.BrightnessMaxSlider.Limits = [data.clim{data.day}(1) data.clim{data.day}(2)];
            app.BrightnessMinSlider.Value = data.clim{data.day}(1);
            app.BrightnessMaxSlider.Value = data.clim{data.day}(2);
            
            % update template plot
            hold(data.ax, 'off');
            imagesc(data.ax, data.template{new_val});
            hold(data.ax, 'on');
            dims = size(data.template{new_val});
            xlim(data.ax, [0 dims(2)]); ylim(data.ax, [0 dims(1)]);
            caxis(data.ax, data.clim{data.day});
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            hold(data.ax, 'off');
            
        end
        
        function [data] = update_roilist(app, data)
            data.num_show = length(data.show_idx);
            roi_str = cell(1,data.num_show);
            for i = 1:data.num_show
                roi_str{i} = ['ROI' num2str(i)];
            end
            app.ROIsListBox.Items = roi_str;
            app.ROIsListBox.ItemsData = 1:data.num_show;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            figpos_default = [680,84,714,522];
            
            % find the root app handle
            figure_handle = findall(groot, 'Type', 'Figure');
            for i = 1:length(figure_handle)
                if strcmp(figure_handle(i).Name, 'Main')
                    idx = i;
                end
            end
            figure_handle = figure_handle(idx);
            data = guidata(figure_handle);
            
            fig_pos = get(figure_handle, 'position');
            set(app.ViewresultsUIFigure, 'position', [fig_pos(1)+20, fig_pos(2)-20, fig_pos(3), fig_pos(4)]);
            
            ax_pos = get(data.ax, 'position');
            ax_pos(3) = ax_pos(3)*1.05;  ax_pos(4) = ax_pos(4)*1.1;
            ax = axes('Parent', app.RegistrationresultPanel, 'position', ax_pos);
            set(ax, 'xtick', [], 'ytick', []);
            set(ax, 'box', 'on');
            set(ax ,'Layer', 'Top')
            handles = findobj(figure_handle, 'Type', 'UIPanel');
            mov_up = 30; h_diff = 40;
            for i = 1:length(handles)
                if strcmp(handles(i).Title, 'Template')
                    set(app.RegistrationresultPanel, 'position', handles(i).Position);
                    comps = get(handles(i), 'Children');
                    for j = 1:length(comps)
                        if strcmp(class(comps(j)), 'matlab.graphics.axis.Axes')
                            set(ax, 'position', comps(j).Position);
                        elseif strcmp(class(comps(j)), 'matlab.ui.control.Slider')
                            if strcmp(comps(j+1).Text, 'Brightness Min')
                                set(app.BrightnessMinSlider, 'position', comps(j).Position);
                                set(app.BrightnessMinSliderLabel, 'position', comps(j+1).Position);
                            elseif strcmp(comps(j+1).Text, 'Brightness Max')
                                set(app.BrightnessMaxSlider, 'position', comps(j).Position);
                                set(app.BrightnessMaxSliderLabel, 'position', comps(j+1).Position);
                            elseif strcmp(comps(j+1).Text, 'Template')
                                set(app.TemplateSlider, 'position', comps(j).Position);
                                set(app.TemplateSliderLabel, 'position', comps(j+1).Position);
                            end
                        end
                    end
                elseif strcmp(handles(i).Title, 'ROIs')
                    pos = handles(i).Position;
                    offset = mov_up+h_diff;
                    set(app.ROIsPanel, 'position', [pos(1), pos(2)+offset, pos(3), pos(4)-offset]);
                    comps = get(handles(i), 'Children');
                    for j = 1:length(comps)
                        if strcmp(class(comps(j)), 'matlab.ui.control.Button') && strcmp(comps(j).Text, 'Add ROI')
                            pos = comps(j).Position;
                            set(app.DeleteROIButton, 'position', [pos(1), pos(2)-offset, pos(3), pos(4)]);
                        elseif strcmp(class(comps(j)), 'matlab.ui.control.Button') && strcmp(comps(j).Text, 'Load')
                            pos = comps(j).Position;
                            set(app.DelonFrameButton, 'position', [pos(1), pos(2)-offset, pos(3), pos(4)]);
                        elseif strcmp(class(comps(j)), 'matlab.ui.control.ListBox')
                            pos = comps(j).Position;
                            set(app.ROIsListBox, 'position', [pos(1), pos(2)+mov_up, pos(3), pos(4)-offset]);
                        end
                    end
                elseif strcmp(handles(i).Title, 'Display settings')
                    pos = handles(i).Position;
                    pos = [pos(1), pos(2)+mov_up+h_diff, pos(3), pos(4)];
                    set(app.DisplaysettingsPanel, 'position', pos);
                elseif strcmp(handles(i).Title, 'Register templates from ROIs')
                    pos = handles(i).Position;
                    pos = [pos(1), pos(2), pos(3), pos(4)+h_diff+mov_up];
                    set(app.MatchROIsPanel, 'position', pos);
                end
            end
            
            % rename variables for convenience
            data.template = data.template_reg;
            data.roi_center = data.roi_center_reg;
            data.roi_contour = data.roi_contour_reg;
            data.roi_contour_combined = cell(1,data.num_data);
            for i = 1:data.num_data
                data.roi_contour_combined{i} = cell2mat(data.roi_contour_reg{i}');
            end
            
            % show first template
            data.ax = ax;
            imagesc(data.ax, data.template{1});
            hold(data.ax, 'on');
            dims = size(data.template{1});
            xlim(data.ax, [0 dims(2)]); ylim(ax, [0 dims(1)]);
            set(data.ax, 'xtick', [], 'ytick', []);
            colormap(data.ax, gray);
            num_data = length(data.template);
            data.num_data = num_data;
            hold(data.ax, 'off');
            
            % initialize
            data.roi_cc = jet(50); 
            data.roi_cc = data.roi_cc(randperm(50),:);
            data.mksz = app.ROIsizeEditField.Value*10;
            data.mkalpha = app.TransparencyEditField.Value;
            data.roi_coord = [];
            data.roi_idx = NaN;
            data.num_found = NaN;
            data.show_idx = [];

            % update slider
            app.TemplateSlider.Limits = [1 num_data];
            app.TemplateSlider.MajorTicks = 1:num_data;
            data.day = 1;
            app.BrightnessMinSlider.Limits = [data.clim{1}(1) data.clim{1}(2)];
            app.BrightnessMaxSlider.Limits = [data.clim{1}(1) data.clim{1}(2)];
            app.BrightnessMinSlider.Value = data.clim{1}(1);
            app.BrightnessMaxSlider.Value = data.clim{1}(2);
            
            % update show min max field
            app.ShowmindaysEditField.Value = 1;
            app.ShowmaxdaysEditField.Value = data.num_data;
            
            % store data
            guidata(app.ViewresultsUIFigure, data);
        end

        % Button pushed function: MatchROIsButton
        function MatchROIsButtonPushed(app, event)
            data = guidata(app.ViewresultsUIFigure);
            
            data.roi_list = nan(0, data.num_data);
            data.assigned_flag = cell(1, data.num_data);
            for n = 1:data.num_data
                data.assigned_flag{n} = false(size(data.roi_center{n},1), 1);
            end
            
            max_dist = app.MaxdistEditField.Value;
            for n = 1:data.num_data
                for i = 1:size(data.roi_center{n},1)
                    if data.assigned_flag{n}(i)==0
                        data.roi_list(end+1,:) = nan(1, data.num_data);
                        data.roi_list(end, n) = i;
                        data.assigned_flag{n}(i) = 1;
                        % find the same roi in the following days
                        for m = n+1:data.num_data
                            d = pdist2(data.roi_center{n}(i,:), data.roi_center{m});
                            idx = find(d<max_dist);
                            if ~isempty(idx)
                                [~,sort_idx] = sort(d(idx),'ascend');
                                for j = 1:length(sort_idx)
                                    if data.assigned_flag{m}(idx(sort_idx(j)))==0
                                        data.roi_list(end,m) = idx(sort_idx(j));
                                        data.assigned_flag{m}(idx(sort_idx(j))) = 1;
                                        break;
                                    end
                                end
                            end
                        end
                    end
                end
            end
            
            data.found_flag = ~isnan(data.roi_list);
            data.num_found = sum(data.found_flag,2);
            data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
                & data.num_found<=app.ShowmaxdaysEditField.Value);
            data.roi_idx = 1;
            
            % update ROI list box and figure
            data = update_roilist(app, data);
            update_figure(app, data);
            
            guidata(app.ViewresultsUIFigure, data);
            
        end

        % Window scroll wheel function: ViewresultsUIFigure
        function ViewresultsUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            new_val = app.TemplateSlider.Value + round(verticalScrollCount);
            new_val = max(min(new_val, data.num_data), 1);
            data.day = new_val;
    
            % update template and roi
            update_template(app, data, new_val);
            update_figure(app, data);
            
            % save data
            guidata(app.ViewresultsUIFigure, data);
            
        end

        % Value changed function: BrightnessMinSlider
        function BrightnessMinSliderValueChanged(app, event)
            value = app.BrightnessMinSlider.Value;
            data = guidata(app.ViewresultsUIFigure);
            data.clim{data.day}(1) = value;
            
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: BrightnessMaxSlider
        function BrightnessMaxSliderValueChanged(app, event)
            value = app.BrightnessMaxSlider.Value;
            data = guidata(app.ViewresultsUIFigure);
            data.clim{data.day}(2) = value;
            
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: TemplateSlider
        function TemplateSliderValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            % round up
            new_val = round(app.TemplateSlider.Value);
            data.day = new_val;
            
            app.BrightnessMinSlider.Limits = [min(data.template{data.day}(:)) max(data.template{data.day}(:))];
            app.BrightnessMaxSlider.Limits = [min(data.template{data.day}(:)) max(data.template{data.day}(:))];
            app.BrightnessMinSlider.Value = data.clim{data.day}(1);
            app.BrightnessMaxSlider.Value = data.clim{data.day}(2);
            
            % update template and roi
            update_template(app, data, new_val);
            update_figure(app, data);
            
            % save data
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: DisplayallCheckBox
        function DisplayallCheckBoxValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
        end

        % Value changed function: ROInumberCheckBox
        function ROInumberCheckBoxValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
        end

        % Value changed function: DisplayspatialcomponentCheckBox
        function DisplayspatialcomponentCheckBoxValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            update_figure(app, data);
        end

        % Value changed function: ShowmindaysEditField
        function ShowmindaysEditFieldValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isnan(data.num_found); return; end
            data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
                & data.num_found<=app.ShowmaxdaysEditField.Value);
            
            update_roilist(app, data);
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: ShowmaxdaysEditField
        function ShowmaxdaysEditFieldValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isnan(data.num_found); return; end
            data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
                & data.num_found<=app.ShowmaxdaysEditField.Value);
            
            update_roilist(app, data);
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: ROIsizeEditField
        function ROIsizeEditFieldValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            data.mksz = app.ROIsizeEditField.Value*10;
            
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: TransparencyEditField
        function TransparencyEditFieldValueChanged(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            data.mkalpha = app.TransparencyEditField.Value;
            
            update_figure(app, data);
            guidata(app.ViewresultsUIFigure, data);
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            % get current roi selection index
            data = guidata(app.ViewresultsUIFigure);
            data.roi_idx = app.ROIsListBox.Value;
            guidata(app.ViewresultsUIFigure, data);
            update_figure(app, data);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            roi_list = data.roi_list;
            template = data.template;
            roi_center = data.roi_center;
            roi_contour = data.roi_contour;
            
            [fname, fpath] = uiputfile({'*.mat','MAT-files'}, 'Save registered ROI result');
            if fname==0; return; end
            save([fpath fname], 'roi_list', 'template', 'roi_center', 'roi_contour', '-v7.3');
        end

        % Size changed function: ViewresultsUIFigure
        function ViewresultsUIFigureSizeChanged(app, event)
            % doesn't work for some reasons
            
%             data = guidata(app.ViewresultsUIFigure);
%             fig_pos = app.ViewresultsUIFigure.Position;
%             fig_margin = [20 20 10 10];  % left, right, top, bottom
%             spacing = 10;
%             
%             % set sizes
%             fig_sz = fig_pos(3:4);
%             title_sz = [151,22]; % width, height
%             button_height = 22;
%             panel_sz = [172, 85];
%             listbox_sz = [panel_sz(1), fig_sz(2)-2*panel_sz(2)-fig_margin(3)-fig_margin(4)-spacing*3];
%             ax_sz = [fig_sz(1)-fig_margin(1)-fig_margin(2)-panel_sz(1)-spacing, listbox_sz(2)+panel_sz(2)];
%             templ_number_bar_sz = [ax_sz(1), 3];
%             brightness_sz = [ax_sz(1)*0.35, 3];
%             
%             % set positions: left, bottom, width, height
%             title_pos = [fig_margin(1)+ax_sz(1)/2, fig_sz(2)-templ_number_bar_sz(1)*2-brightness_sz(1)-...
%                 ax_sz(2)-spacing*3, title_sz(1), title_sz(2)];
%             ax_pos = [fig_margin(1), templ_number_bar_sz(1)*2-brightness_sz(1)-ax_sz(2)-spacing*3, ax_sz(1), ax_sz(2)];
%             listbox_pos = [fig_sz(1)-ax_sz(1)-fig_margin(1)-spacing, fig_sz(2)-panel_sz(2)*2-button_height-spacing*3-...
%                 fig_margin(4),listbox_sz(1), listbox_sz(2)];
%             display_panel_pos = [listbox_pos(1), fig_sz(2)-panel_sz(2)-button_height-spacing*2-fig_margin(4), ...
%                 panel_sz(1), panel_sz(2)];
%             match_panel_pos = [listbox_pos(1), fig_sz(2)-button_height-spacing*2-fig_margin(4), ...
%                 panel_sz(1), panel_sz(2)];
%             brightness_min_pos = get(app.brightness_min_pos, 'position');
%             brightness_min_pos = [brightness_min_pos(1), brightness_min_pos(2), brightness_sz(1), brightness_sz(2)];
%             brightness_max_pos = get(app.brightness_max_pos, 'position');
%             brightness_max_pos = [brightness_max_pos(1), brightness_max_pos(2), brightness_sz(1), brightness_sz(2)];
%             templ_number_pos = get(app.TemplateSlider, 'position');
%             templ_number_pos = [templ_number_pos(1), templ_number_pos(2), templ_number_bar_sz(1), templ_number_bar_sz(2)];
%             
%             % update positions
%             set(data.ax, 'position', ax_pos);
%             set(app.ROIsListBox, 'position', listbox_pos);
%             set(app.RegistrationresultLabel, 'position', title_pos);
%             set(app.DisplaysettingsPanel, 'position', display_panel_pos);
%             set(app.MatchROIsPanel, 'position', match_panel_pos);
%             set(app.brightness_min_pos, 'position', brightness_min_pos);
%             set(app.brightness_max_pos, 'position', brightness_max_pos);
%             set(app.TemplateSlider, 'position', templ_number_pos);
            
        end

        % Callback function
        function AddROIButtonPushed(app, event)

%             data = guidata(app.ViewresultsUIFigure);
%             if isempty(data); return; end
%             
%             data.day = app.TemplateSlider.Value;
%             data.roi_list = cat(2, data.roi_list, nan(1,data.num_day));
%             data.num_found =  cat(2, data.num_found, zeros(1,data.num_day));
%             data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
%                 & data.num_found<=app.ShowmaxdaysEditField.Value);
%             
%             data = update_roilist(app, data);
%             update_figure(app, data);
%             
%             guidata(app.ViewresultsUIFigure, data);
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            data = guidata(app.ViewresultsUIFigure);
            if isempty(data); return; end
            
            data.day = app.TemplateSlider.Value;
            keep_idx = true(1, size(data.roi_list,1));
            keep_idx(data.show_idx(data.roi_idx)) = 0;
            data.roi_list = data.roi_list(keep_idx,:);
            data.num_found = data.num_found(keep_idx,:);
            data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
                & data.num_found<=app.ShowmaxdaysEditField.Value);
            
            data = update_roilist(app, data);
            update_figure(app, data);
            
            guidata(app.ViewresultsUIFigure, data);
        end

        % Callback function
        function AddonFrameButtonPushed(app, event)
%             data = guidata(app.ViewresultsUIFigure);
%             if isempty(data); return; end
%             
%             % select from figure
%             current_roi = drawcircle(data.ax, 'visible', 'off');
%             try
%                 data.roi_coord(data.roi_idx,data.day,:) = current_roi.Position;
%             catch ME     
%             end
%             update_figure(app, data);
%             
%             guidata(app.ViewresultsUIFigure, data);
        end

        % Button pushed function: DelonFrameButton
        function DelonFrameButtonPushed(app, event)
            % get current roi selection index
            data = guidata(app.ViewresultsUIFigure);
            data.roi_idx = app.ROIsListBox.Value;
            data.roi_list(data.show_idx(data.roi_idx), data.day) = NaN;
            data.num_found(data.show_idx(data.roi_idx), data.day) = 0;
%             data.show_idx = find(data.num_found>=app.ShowmindaysEditField.Value ...
%                 & data.num_found<=app.ShowmaxdaysEditField.Value);
            
            guidata(app.ViewresultsUIFigure, data);
            update_figure(app, data);
        end

        % Key release function: ViewresultsUIFigure
        function ViewresultsUIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'd'
                    DelonFrameButtonPushed(app, event);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ViewresultsUIFigure = uifigure;
            app.ViewresultsUIFigure.Name = 'View results';
            app.ViewresultsUIFigure.SizeChangedFcn = createCallbackFcn(app, @ViewresultsUIFigureSizeChanged, true);
            app.ViewresultsUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ViewresultsUIFigureWindowScrollWheel, true);
            app.ViewresultsUIFigure.KeyReleaseFcn = createCallbackFcn(app, @ViewresultsUIFigureKeyRelease, true);
            app.MatchROIsPanel = uipanel(app.ViewresultsUIFigure);
            app.MatchROIsPanel.Title = 'Match ROIs';
            app.MatchROIsButton = uibutton(app.MatchROIsPanel, 'push');
            app.MatchROIsButton.ButtonPushedFcn = createCallbackFcn(app, @MatchROIsButtonPushed, true);
            app.MatchROIsButton.Text = 'Match ROIs';
            app.MaxdistEditFieldLabel = uilabel(app.MatchROIsPanel);
            app.MaxdistEditFieldLabel.Text = 'Max dist.';
            app.MaxdistEditField = uieditfield(app.MatchROIsPanel, 'numeric');
            app.MaxdistEditField.Value = 5;
            app.ShowmindaysEditFieldLabel = uilabel(app.MatchROIsPanel);
            app.ShowmindaysEditFieldLabel.Text = 'Show min. days';
            app.ShowmindaysEditField = uieditfield(app.MatchROIsPanel, 'numeric');
            app.ShowmindaysEditField.ValueChangedFcn = createCallbackFcn(app, @ShowmindaysEditFieldValueChanged, true);
            app.ShowmindaysEditField.Tooltip = {'Minimum tracked days for a ROI to be shown'};
            app.ShowmaxdaysEditFieldLabel = uilabel(app.MatchROIsPanel);
            app.ShowmaxdaysEditFieldLabel.Text = 'Show max. days';
            app.ShowmaxdaysEditField = uieditfield(app.MatchROIsPanel, 'numeric');
            app.ShowmaxdaysEditField.ValueChangedFcn = createCallbackFcn(app, @ShowmaxdaysEditFieldValueChanged, true);
            app.ShowmaxdaysEditField.Tooltip = {'Maximum tracked days for a ROI to be shown'};
            app.SaveButton = uibutton(app.MatchROIsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DisplaysettingsPanel = uipanel(app.ViewresultsUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.DisplayallCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.DisplayallCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayallCheckBoxValueChanged, true);
            app.DisplayallCheckBox.Text = 'Display all';
            app.ROInumberCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.ROInumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @ROInumberCheckBoxValueChanged, true);
            app.ROInumberCheckBox.Text = 'ROI number';
            app.ROIsizeEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.ROIsizeEditFieldLabel.Text = 'ROI size';
            app.ROIsizeEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.ROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ROIsizeEditFieldValueChanged, true);
            app.ROIsizeEditField.Value = 10;
            app.TransparencyEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.TransparencyEditFieldLabel.Text = 'Alpha';
            app.TransparencyEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.TransparencyEditField.ValueChangedFcn = createCallbackFcn(app, @TransparencyEditFieldValueChanged, true);
            app.TransparencyEditField.Value = 0.4;
            app.DisplayspatialcomponentCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.DisplayspatialcomponentCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayspatialcomponentCheckBoxValueChanged, true);
            app.DisplayspatialcomponentCheckBox.Text = 'Display spatial component';
            app.RegistrationresultPanel = uipanel(app.ViewresultsUIFigure);
            app.RegistrationresultPanel.Title = 'Registration result';
            app.TemplateSliderLabel = uilabel(app.RegistrationresultPanel);
            app.TemplateSliderLabel.Text = 'Template';
            app.TemplateSlider = uislider(app.RegistrationresultPanel);
            app.TemplateSlider.MajorTicks = [];
            app.TemplateSlider.ValueChangedFcn = createCallbackFcn(app, @TemplateSliderValueChanged, true);
            app.TemplateSlider.MinorTicks = [];
            app.BrightnessMaxSliderLabel = uilabel(app.RegistrationresultPanel);
            app.BrightnessMaxSliderLabel.Text = 'Brightness Max';
            app.BrightnessMaxSlider = uislider(app.RegistrationresultPanel);
            app.BrightnessMaxSlider.MajorTicks = [];
            app.BrightnessMaxSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessMaxSliderValueChanged, true);
            app.BrightnessMaxSlider.MinorTicks = [];
            app.BrightnessMinSliderLabel = uilabel(app.RegistrationresultPanel);
            app.BrightnessMinSliderLabel.Text = 'Brightness Min';
            app.BrightnessMinSlider = uislider(app.RegistrationresultPanel);
            app.BrightnessMinSlider.MajorTicks = [];
            app.BrightnessMinSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessMinSliderValueChanged, true);
            app.BrightnessMinSlider.MinorTicks = [];
            app.ROIsPanel = uipanel(app.ViewresultsUIFigure);
            app.ROIsPanel.Title = 'ROIs';
            app.ROIsListBox = uilistbox(app.ROIsPanel);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {};
            app.DeleteROIButton = uibutton(app.ROIsPanel, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Text = 'Delete ROI';
            app.DelonFrameButton = uibutton(app.ROIsPanel, 'push');
            app.DelonFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DelonFrameButtonPushed, true);
            app.DelonFrameButton.Text = 'Del on Frame';
        end
    end

    methods (Access = public)

        % Construct app
        function app = view_multisession_align

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ViewresultsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ViewresultsUIFigure)
        end
    end
end